classdef artifactParameterTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        convWindowsSpinnerLabel    matlab.ui.control.Label
        convWindowsSpinner         matlab.ui.control.Spinner
        threshWindowsSpinnerLabel  matlab.ui.control.Label
        threshWindowsSpinner       matlab.ui.control.Spinner
        threshAmpstdSpinnerLabel   matlab.ui.control.Label
        threshAmpstdSpinner        matlab.ui.control.Spinner
        movMADsSpinnerLabel        matlab.ui.control.Label
        movMADsSpinner             matlab.ui.control.Spinner
        RightPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes4                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        reference % 405nm
        signal %465nm
        fs %sampling frequency
        blurredReference %smoothed reference (baseline estimate)
        kernel %kernel to be convolved with reference signal to generate blurredReference
        MAD %median absolute deviation of reference to baseline
        threshold %MAD threshold beyond which artifacts will be ID'd
        badIndex %indices that exceed threshold (should be removed)
        fixedSignal %signal with artifacts removed
        fixedReference %reference with artifacts removed
        blurredReferenceNoPad % remove 0 padding from convolution (make nan)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, signal, reference)
            hold(app.UIAxes, "on") %prevent overwriting plot
            plot(app.UIAxes, signal, 'b') %465nm
            plot(app.UIAxes, reference, 'm') %405nm
            hold(app.UIAxes, "off")
            
            app.reference= reference; %save variables as a 'property' of the app so we can use it later
            app.signal= signal;
            app.fs= 40; %40hz
                                
        end

        % Callback function
        function convWindowEditFieldValueChanged(app, event)
            value = app.convWindowEditField.Value;
       

        end

        % Value changed function: convWindowsSpinner
        function convWindowsSpinnerValueChanged(app, event)
            value = app.convWindowsSpinner.Value*app.fs; % * fs for time in s
           
            %update blurredReference calculation and plot
            app.kernel = ones(1, (app.convWindowsSpinner.Value*app.fs)) / (app.convWindowsSpinner.Value*app.fs); %kernel to be convolved with reference for "blurring"
            app.blurredReference = conv(app.reference, app.kernel, 'same');
            plot(app.UIAxes2,app.blurredReference, 'm'); %visualizing 0 padded convolution just to make sure I'm removing padding correctly; seems to work fine
            
%                 %todo: was getting rid of 0 padding on conv but more work
% %                 than it's probably worth
%             app.blurredReferenceNoPad= app.blurredReference();
%             app.blurredReferenceNoPad(1:(app.convWindowsSpinner.Value*app.fs)/2)=nan;
%             app.blurredReferenceNoPad(end-(app.convWindowsSpinner.Value*app.fs/2):end)= nan;
%             hold(app.UIAxes2, "on");
%             plot(app.UIAxes2, app.blurredReferenceNoPad, 'k')
%             hold(app.UIAxes2, "off");
            
%             app.UIAxes2.YLim= app.UIAxes.YLim; %conv padding makes some extreme values here so just sync with plot 1
%             app.UIAxes2.XLim= app.UIAxes.XLim;
            
            
            %update MAD calculation and plot
            %original method 
%             app.MAD= abs(app.reference - app.blurredReference); %original method, using conv()
            %adapted method
            app.MAD= movmad(app.reference, (app.movMADsSpinner.Value*app.fs)); %new method, using movmad()

            plot(app.UIAxes3, app.MAD, 'k');
            hold(app.UIAxes3, "on") %hold for threshold overlay

            %update artifact identification and plot
            app.threshold= movmean(app.MAD, app.threshWindowsSpinner.Value*app.fs)+std(app.MAD)*app.threshAmpstdSpinner.Value; %adjust dynamic threshold of MAD
            plot(app.UIAxes3, app.threshold, 'r') %plot dynamic threshold
            hold(app.UIAxes3, "off");%release hold 
            
%             app.UIAxes3.YLim= [0,10] %zero padding of conv makes some extreme values so just cap the yim 
%             app.UIAxes3,XLim=app.UIAxes.XLim
            
            app.badIndex= app.MAD>app.threshold;%MAD(trendAgrees==1)>threshold(trendAgrees==1);
            app.fixedSignal= app.signal;
            app.fixedSignal(app.badIndex)=nan;
            
            app.fixedReference= app.reference;
            app.fixedReference(app.badIndex)=nan;
            
            plot(app.UIAxes4, app.fixedSignal, 'b')            
            hold(app.UIAxes4, "on")

            plot(app.UIAxes4, app.fixedReference, 'm')
            hold(app.UIAxes4, "off")
%             app.UIAxes4.YLim= app.UIAxes.YLim; %conv padding makes some extreme values here so just sync with plot 1
%             app.UIAxes4.XLim= app.UIAxes.XLim;
            linkaxes([app.UIAxes, app.UIAxes2, app.UIAxes3, app.UIAxes4], 'x')
            linkaxes([app.UIAxes, app.UIAxes2, app.UIAxes4], 'y')

        end

        % Value changed function: threshWindowsSpinner
        function threshWindowsSpinnerValueChanged(app, event)
            value = app.threshWindowsSpinner.Value*app.fs;
             %update blurredReference calculation and plot
            app.kernel = ones(1, (app.convWindowsSpinner.Value*app.fs)) / (app.convWindowsSpinner.Value*app.fs); %kernel to be convolved with reference for "blurring"
            app.blurredReference = conv(app.reference, app.kernel, 'same');
            plot(app.UIAxes2,app.blurredReference, 'm'); %visualizing 0 padded convolution just to make sure I'm removing padding correctly; seems to work fine
            
%                 %todo: was getting rid of 0 padding on conv but more work
% %                 than it's probably worth
%             app.blurredReferenceNoPad= app.blurredReference();
%             app.blurredReferenceNoPad(1:(app.convWindowsSpinner.Value*app.fs)/2)=nan;
%             app.blurredReferenceNoPad(end-(app.convWindowsSpinner.Value*app.fs/2):end)= nan;
%             hold(app.UIAxes2, "on");
%             plot(app.UIAxes2, app.blurredReferenceNoPad, 'k')
%             hold(app.UIAxes2, "off");
            
%             app.UIAxes2.YLim= app.UIAxes.YLim; %conv padding makes some extreme values here so just sync with plot 1
%             app.UIAxes2.XLim= app.UIAxes.XLim;
            
            
            %update MAD calculation and plot
            %original method 
%             app.MAD= abs(app.reference - app.blurredReference); %original method, using conv()
            %adapted method
            app.MAD= movmad(app.reference, (app.movMADsSpinner.Value*app.fs)); %new method, using movmad()

            plot(app.UIAxes3, app.MAD, 'k');
            hold(app.UIAxes3, "on") %hold for threshold overlay

            %update artifact identification and plot
            app.threshold= movmean(app.MAD, app.threshWindowsSpinner.Value*app.fs)+std(app.MAD)*app.threshAmpstdSpinner.Value; %adjust dynamic threshold of MAD
            plot(app.UIAxes3, app.threshold, 'r') %plot dynamic threshold
            hold(app.UIAxes3, "off");%release hold 
            
%             app.UIAxes3.YLim= [0,10] %zero padding of conv makes some extreme values so just cap the yim 
%             app.UIAxes3,XLim=app.UIAxes.XLim
            
            app.badIndex= app.MAD>app.threshold;%MAD(trendAgrees==1)>threshold(trendAgrees==1);
            app.fixedSignal= app.signal;
            app.fixedSignal(app.badIndex)=nan;
            
            app.fixedReference= app.reference;
            app.fixedReference(app.badIndex)=nan;
            
            plot(app.UIAxes4, app.fixedSignal, 'b')            
            hold(app.UIAxes4, "on")

            plot(app.UIAxes4, app.fixedReference, 'm')
            hold(app.UIAxes4, "off")
%             app.UIAxes4.YLim= app.UIAxes.YLim; %conv padding makes some extreme values here so just sync with plot 1
%             app.UIAxes4.XLim= app.UIAxes.XLim;
            linkaxes([app.UIAxes, app.UIAxes2, app.UIAxes3, app.UIAxes4], 'x')
            linkaxes([app.UIAxes, app.UIAxes2, app.UIAxes4], 'y')
        end

        % Value changed function: threshAmpstdSpinner
        function threshAmpstdSpinnerValueChanged(app, event)
            value = app.threshAmpstdSpinner.Value;
              %update blurredReference calculation and plot
            app.kernel = ones(1, (app.convWindowsSpinner.Value*app.fs)) / (app.convWindowsSpinner.Value*app.fs); %kernel to be convolved with reference for "blurring"
            app.blurredReference = conv(app.reference, app.kernel, 'same');
            plot(app.UIAxes2,app.blurredReference, 'm'); %visualizing 0 padded convolution just to make sure I'm removing padding correctly; seems to work fine
            
%                 %todo: was getting rid of 0 padding on conv but more work
% %                 than it's probably worth
%             app.blurredReferenceNoPad= app.blurredReference();
%             app.blurredReferenceNoPad(1:(app.convWindowsSpinner.Value*app.fs)/2)=nan;
%             app.blurredReferenceNoPad(end-(app.convWindowsSpinner.Value*app.fs/2):end)= nan;
%             hold(app.UIAxes2, "on");
%             plot(app.UIAxes2, app.blurredReferenceNoPad, 'k')
%             hold(app.UIAxes2, "off");
            
%             app.UIAxes2.YLim= app.UIAxes.YLim; %conv padding makes some extreme values here so just sync with plot 1
%             app.UIAxes2.XLim= app.UIAxes.XLim;
            
            
            %update MAD calculation and plot
            %original method 
%             app.MAD= abs(app.reference - app.blurredReference); %original method, using conv()
            %adapted method
            app.MAD= movmad(app.reference, (app.movMADsSpinner.Value*app.fs)); %new method, using movmad()

            plot(app.UIAxes3, app.MAD, 'k');
            hold(app.UIAxes3, "on") %hold for threshold overlay

            %update artifact identification and plot
            app.threshold= movmean(app.MAD, app.threshWindowsSpinner.Value*app.fs)+std(app.MAD)*app.threshAmpstdSpinner.Value; %adjust dynamic threshold of MAD
            plot(app.UIAxes3, app.threshold, 'r') %plot dynamic threshold
            hold(app.UIAxes3, "off");%release hold 
            
%             app.UIAxes3.YLim= [0,10] %zero padding of conv makes some extreme values so just cap the yim 
%             app.UIAxes3,XLim=app.UIAxes.XLim
            
            app.badIndex= app.MAD>app.threshold;%MAD(trendAgrees==1)>threshold(trendAgrees==1);
            app.fixedSignal= app.signal;
            app.fixedSignal(app.badIndex)=nan;
            
            app.fixedReference= app.reference;
            app.fixedReference(app.badIndex)=nan;
            
            plot(app.UIAxes4, app.fixedSignal, 'b')            
            hold(app.UIAxes4, "on")

            plot(app.UIAxes4, app.fixedReference, 'm')
            hold(app.UIAxes4, "off")
%             app.UIAxes4.YLim= app.UIAxes.YLim; %conv padding makes some extreme values here so just sync with plot 1
%             app.UIAxes4.XLim= app.UIAxes.XLim;
            linkaxes([app.UIAxes, app.UIAxes2, app.UIAxes3, app.UIAxes4], 'x')
            linkaxes([app.UIAxes, app.UIAxes2, app.UIAxes4], 'y')
        end

        % Value changed function: movMADsSpinner
        function movMADsSpinnerValueChanged(app, event)
            value = app.movMADsSpinner.Value*app.fs;
             %update blurredReference calculation and plot
            app.kernel = ones(1, (app.convWindowsSpinner.Value*app.fs)) / (app.convWindowsSpinner.Value*app.fs); %kernel to be convolved with reference for "blurring"
            app.blurredReference = conv(app.reference, app.kernel, 'same');
            plot(app.UIAxes2,app.blurredReference, 'm'); %visualizing 0 padded convolution just to make sure I'm removing padding correctly; seems to work fine
            
%                 %todo: was getting rid of 0 padding on conv but more work
% %                 than it's probably worth
%             app.blurredReferenceNoPad= app.blurredReference();
%             app.blurredReferenceNoPad(1:(app.convWindowsSpinner.Value*app.fs)/2)=nan;
%             app.blurredReferenceNoPad(end-(app.convWindowsSpinner.Value*app.fs/2):end)= nan;
%             hold(app.UIAxes2, "on");
%             plot(app.UIAxes2, app.blurredReferenceNoPad, 'k')
%             hold(app.UIAxes2, "off");
            
%             app.UIAxes2.YLim= app.UIAxes.YLim; %conv padding makes some extreme values here so just sync with plot 1
%             app.UIAxes2.XLim= app.UIAxes.XLim;
            
            
            %update MAD calculation and plot
            %original method 
%             app.MAD= abs(app.reference - app.blurredReference); %original method, using conv()
            %adapted method
            app.MAD= movmad(app.reference, (app.movMADsSpinner.Value*app.fs)); %new method, using movmad()

            plot(app.UIAxes3, app.MAD, 'k');
            hold(app.UIAxes3, "on") %hold for threshold overlay

            %update artifact identification and plot
            app.threshold= movmean(app.MAD, app.threshWindowsSpinner.Value*app.fs)+std(app.MAD)*app.threshAmpstdSpinner.Value; %adjust dynamic threshold of MAD
            plot(app.UIAxes3, app.threshold, 'r') %plot dynamic threshold
            hold(app.UIAxes3, "off");%release hold 
            
%             app.UIAxes3.YLim= [0,10] %zero padding of conv makes some extreme values so just cap the yim 
%             app.UIAxes3,XLim=app.UIAxes.XLim
            
            app.badIndex= app.MAD>app.threshold;%MAD(trendAgrees==1)>threshold(trendAgrees==1);
            app.fixedSignal= app.signal;
            app.fixedSignal(app.badIndex)=nan;
            
            app.fixedReference= app.reference;
            app.fixedReference(app.badIndex)=nan;
 
            plot(app.UIAxes4, app.fixedSignal, 'b')            
            hold(app.UIAxes4, "on")

            plot(app.UIAxes4, app.fixedReference, 'm')
            hold(app.UIAxes4, "off")
%             app.UIAxes4.YLim= app.UIAxes.YLim; %conv padding makes some extreme values here so just sync with plot 1
%             app.UIAxes4.XLim= app.UIAxes.XLim;
            linkaxes([app.UIAxes, app.UIAxes2, app.UIAxes3, app.UIAxes4], 'x')
            linkaxes([app.UIAxes, app.UIAxes2, app.UIAxes4], 'y')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {449, 449};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {203, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.convWindowsSpinnerLabel = uilabel(app.LeftPanel);
            app.convWindowsSpinnerLabel.Text = 'convWindow (s)';
            app.convWindowsSpinner = uispinner(app.LeftPanel);
            app.convWindowsSpinner.Limits = [1 Inf];
            app.convWindowsSpinner.ValueChangedFcn = createCallbackFcn(app, @convWindowsSpinnerValueChanged, true);
            app.convWindowsSpinner.Value = 1;
            app.threshWindowsSpinnerLabel = uilabel(app.LeftPanel);
            app.threshWindowsSpinnerLabel.Text = 'threshWindow (s)';
            app.threshWindowsSpinner = uispinner(app.LeftPanel);
            app.threshWindowsSpinner.Limits = [1 Inf];
            app.threshWindowsSpinner.ValueChangedFcn = createCallbackFcn(app, @threshWindowsSpinnerValueChanged, true);
            app.threshWindowsSpinner.Value = 15;
            app.threshAmpstdSpinnerLabel = uilabel(app.LeftPanel);
            app.threshAmpstdSpinnerLabel.Text = 'threshAmp (# std)';
            app.threshAmpstdSpinner = uispinner(app.LeftPanel);
            app.threshAmpstdSpinner.ValueChangedFcn = createCallbackFcn(app, @threshAmpstdSpinnerValueChanged, true);
            app.threshAmpstdSpinner.Value = 3;
            app.movMADsSpinnerLabel = uilabel(app.LeftPanel);
            app.movMADsSpinnerLabel.Text = 'movMAD (s)';
            app.movMADsSpinner = uispinner(app.LeftPanel);
            app.movMADsSpinner.Limits = [1 Inf];
            app.movMADsSpinner.ValueChangedFcn = createCallbackFcn(app, @movMADsSpinnerValueChanged, true);
            app.movMADsSpinner.Value = 10;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '"raw"')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'mV')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, '"blurred" reference (to be subtracted)')
            xlabel(app.UIAxes2, 'time')
            ylabel(app.UIAxes2, 'smoothed 405nm')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Median Absolute Deviation MAD (reference - baseline estimate)')
            xlabel(app.UIAxes3, 'time')
            ylabel(app.UIAxes3, 'MAD')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Artifacts removed')
            xlabel(app.UIAxes4, 'time')
            ylabel(app.UIAxes4, 'mV')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = artifactParameterTest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end